classdef SentinelScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sentinel                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PrintReportButton           matlab.ui.control.Button
        PlotTableButton             matlab.ui.control.Button
        AverageGammaPassRateGauge   matlab.ui.control.Gauge
        AverageGammaPassRateLabel   matlab.ui.control.Label
        PassingCritEditField        matlab.ui.control.NumericEditField
        PassingCriteriaLabel        matlab.ui.control.Label
        GammathreshEditField        matlab.ui.control.NumericEditField
        GammathreshLabel            matlab.ui.control.Label
        GammadtammEditField         matlab.ui.control.NumericEditField
        GammadtammLabel             matlab.ui.control.Label
        GammaDDEditField            matlab.ui.control.NumericEditField
        GammaDDLabel                matlab.ui.control.Label
        OutputFolderLabel           matlab.ui.control.Label
        LOGROOTFolderLabel          matlab.ui.control.Label
        StartMonitoringSwitch       matlab.ui.control.Switch
        StartMonitoringSwitchLabel  matlab.ui.control.Label
        DCMROOTFolderLabel          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        OutputFolder                matlab.ui.control.EditField
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        RESULTSTABLELabel           matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        TRAJECTORYFILESFOUNDLabel   matlab.ui.control.Label
        PLANFILESFOUNDLabel         matlab.ui.control.Label
        Status_2                    matlab.ui.control.TextArea
        TDSFolder                   matlab.ui.control.EditField
        dcmFolder                   matlab.ui.control.EditField
        Status                      matlab.ui.control.TextArea
        GammaAxes                   matlab.ui.control.UIAxes
        ActualAxes                  matlab.ui.control.UIAxes
        ExpectedAxes                matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % this is the parallel pool
        fileObj1
        fileObj2
        fileObj3
        fileObj4
    end
    
    properties (Access = public)
        
        FigureUp
        tData
        PassingCriteria
        p = '' 
        fname_out
        avg 
        GammaMap 
        image1
        image2
        filePathEval
        filePathRef
        dta
        dd
        thresh
        LogName
        LogID
        LogRx
        LogBeam
        LogMachine
        LogImagePath
        PlanName
        PlanID
        PlanRx
        PlanBeam
        PlanMachine
        PlanImagePath% Description


    end
    
    methods (Access = public)
        
        function FileWatch(app, pathToWatch)
            app.Status.Value = strcat(datestr(datetime('now')),': ', 'Creating Watcher...',pathToWatch);
            app.fileObj1 = System.IO.FileSystemWatcher(pathToWatch);
            app.fileObj1.IncludeSubdirectories = true;
            app.fileObj1.EnableRaisingEvents = true;
            %app.fileObj1.Filter = '*.dcm';
            changeListener = addlistener(app.fileObj1, 'Created', @onChange); 
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), %disp('Monitor Died...'))

             function onChange(~, evt1)
                fileName = char(evt1.FullPath.ToString());
                %disp(fileName);
                binTF = (strfind(fileName(1,:),'RP'));
                %disp(binTF);
                if ~isempty(binTF) > 0 %found a bin file
                    fileName = fileName(1,:);
                    
                        %beep();
                        %call the function
                        %disp(fileName)
                        current_message = app.Status.Value;
                        new_message = {[datestr(datetime('now')),': ', 'Filename: ',fileName]};
                        app.Status.Value = [current_message; new_message];
                        %TableData = app.UITable.Data %current data
                        %app.UITable.Data = [TableData; {datestr(datetime('now')), fileName, 'RTPLAN', 'TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        MLC_CHECK(app, fileName)
                    
        
               end
            end %onChange
    %FileWatch 
        end
    %this is the binary tracj logs
        function FileWatch2(app, pathToWatch2)
            app.Status_2.Value = strcat(datestr(datetime('now')),': ', 'Creating Watcher...',pathToWatch2);
            app.fileObj2 = System.IO.FileSystemWatcher(pathToWatch2);
            app.fileObj2.IncludeSubdirectories = true;
            app.fileObj2.EnableRaisingEvents = true;
            app.fileObj2.Filter = '*.bin';
            %addlistener(app.fileObj2, 'Renamed', @onChange2); 
            addlistener(app.fileObj2, 'Created', @onChange2);
            %addlistener(app.fileObj2, 'Changed', @onChange2);
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), %disp('Monitor Died...'))

             function onChange2(~, evt2)
                fileNameTDS = char(evt2.FullPath.ToString());
                disp(fileNameTDS);
                binTFTDS = (strfind(fileNameTDS(1,:),'.bin'));
                %disp(binTFTDS);

                if contains(fileNameTDS,'KV','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'MV','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'TxPort','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'KVCBCT','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'KVKV','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'Setup','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'MV_kV','IgnoreCase',true)
                    return
                end

                if contains(fileNameTDS,'~PSTMP','IgnoreCase',true)
                    return
                end

                 if contains(fileNameTDS,'Tx_Port','IgnoreCase',true)
                    return
                end


                if ~isempty(binTFTDS) > 0 %found a bin file
                    fileNameTDS = fileNameTDS(1,:);
                    %if isempty(contains(fileNameTDS(1,:),'KV'))
                        %beep();
                        %call the function
                        %disp(fileNameTDS)
                        current_message2 = app.Status_2.Value;
                        new_message2 = {[datestr(datetime('now')),': ', 'TLOG Filename: ',fileNameTDS]};
                        app.Status_2.Value = [current_message2; new_message2];
                        scroll(app.Status_2,'bottom')
                        %TableData = app.UITable.Data %current data
                        %app.UITable.Data = [TableData; {datestr(datetime('now')), fileNameCSV, 'PROCESSED CSV','TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        TLOG_CHECK(app, fileNameTDS)
                    %end
        
               end
            end %onChange
    %FileWatch 
        end
        
       function FileWatch3(app, pathToWatch2)
            app.Status_2.Value = strcat(datestr(datetime('now')),': ', 'Creating Watcher...',pathToWatch2);
            app.fileObj3 = System.IO.FileSystemWatcher(pathToWatch2);
            app.fileObj3.IncludeSubdirectories = false;
            app.fileObj3.EnableRaisingEvents = true;
            app.fileObj3.Filter = '*.xz';
            %addlistener(app.fileObj3, 'Renamed', @onChange3); 
            addlistener(app.fileObj3, 'Changed', @onChange3); 
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), %disp('Monitor Died...'))

             function onChange3(~, evt3)
                fileNameiCOM = char(evt3.FullPath.ToString());
                
                binTFiCOM= (strfind(fileNameiCOM(1,:),'.xz'));
                
                if ~isempty(binTFiCOM) > 0 %found a iCOM
                    %we need to unzip it
                    %UnzipiCOM(fileNameiCOM)
                    %now the filename is simply the filename minus the .xz
                    %bit
                    %fileNameiCOM = fileNameiCOM(1,1:end-3);
                    
                        %beep();
                        %call the function
                        
                        current_message2 = app.Status_2.Value;
                        new_message2 = {[datestr(datetime('now')),': ', 'iCOM Filename: ',fileNameiCOM]};
                        app.Status_2.Value = [current_message2; new_message2];
                        scroll(app.Status_2,'bottom')
                        %TableData = app.UITable.Data %current data
                        %app.UITable.Data = [TableData; {datestr(datetime('now')), fileNameiCOM, 'ELEKTA iCOM','TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        iCOM_CHECK(app, fileNameiCOM)
                    
        
               end
            end 

       end

       function FileWatch4(app, pathToWatch3)
           
            app.fileObj4 = System.IO.FileSystemWatcher(pathToWatch3);
            app.fileObj4.IncludeSubdirectories = true;
            app.fileObj4.EnableRaisingEvents = true;
            app.fileObj4.Filter = '*.csv';
            %addlistener(app.fileObj4, 'Renamed', @onChange4); 
            addlistener(app.fileObj4, 'Created', @onChange4); 
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), %disp('Monitor Died...'))

             function onChange4(~, evt4)
                fileNameCSV = char(evt4.FullPath.ToString());
                %disp(fileNameCSV);
                binTFCSV = contains(fileNameCSV(1,:),'.csv');
                %disp('foung csv')
                if ~isempty(binTFCSV) %found a iCOM
                    %we need to unzip it
                    %UnzipiCOM(fileNameiCOM)
                    %now the filename is simply the filename minus the .xz
                    %bit
                    %fileNameiCOM = fileNameiCOM(1,1:end-3);
                    
                        %beep();
                        %call the function
                        %disp(fileNameCSV)
                        current_message2 = app.Status_2.Value;
                        new_message2 = {[datestr(datetime('now')),': ', 'CSV Filename: ',fileNameCSV]};
                        app.Status_2.Value = [current_message2; new_message2];
                        scroll(app.Status_2,'bottom')
                        %TableData = app.UITable.Data %current data
                        %app.UITable.Data = [TableData; {datestr(datetime('now')), fileNameCSV, 'PROCESSED CSV','TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        CSV_CHECK(app, fileNameCSV)
                    
        
               end
            end 

        end

        function iCOM_CHECK(app, fileNameiCOM)
                 p = app.p;
                 OutFolder = app.OutputFolder.Value;
%                 current_message2 = app.Status_2.Value
% 
%                 new_message2 = {[datestr(datetime('now')),': ', 'Filename: ',Plan.Filename];
%                             [datestr(datetime('now')),': ', 'Patient ID: ',Plan.PatientID];
%                             [datestr(datetime('now')),': ', 'Plan Name: ',Plan.RTPlanLabel];
%                             [datestr(datetime('now')),': ', 'Plan Date: ',Plan.RTPlanDate];
%                             [datestr(datetime('now')),': ', 'Approval Status: ',Plan.ApprovalStatus];
%                             [datestr(datetime('now')),': ', 'Manufacturer: ', Plan.Manufacturer]
%                             ['---------------------','--------------------','-----------------']}
% 
%                 app.Status_2.Value = [current_message2; new_message2]
                %keep the window focused on the latest iteration
                scroll(app.Status,'bottom')
                
                %call the function in parallel
                fcn_icom = parfeval(p,@eFluenceQA_iCOM,5,fileNameiCOM,OutFolder);
                
                %fetch the ouptuts and update the vars
                %[LogName, LogID, LogField, LogMachine, LogRx] = fetchOutputs(fcn_icom)
               
%                 app.LogRx = app.LogRx;
%                 app.LogBeam = app.LogBeam;
%                 app.LogMachine = app.LogMachine;
%                 app.LogImagePath = app.LogImagePath;


        end

        function CSV_CHECK(app, fileNameCSV)
                 OutFolder = app.OutputFolder.Value;
%                 current_message2 = app.Status_2.Value
% 
%                 new_message2 = {[datestr(datetime('now')),': ', 'Filename: ',Plan.Filename];
%                             [datestr(datetime('now')),': ', 'Patient ID: ',Plan.PatientID];
%                             [datestr(datetime('now')),': ', 'Plan Name: ',Plan.RTPlanLabel];
%                             [datestr(datetime('now')),': ', 'Plan Date: ',Plan.RTPlanDate];
%                             [datestr(datetime('now')),': ', 'Approval Status: ',Plan.ApprovalStatus];
%                             [datestr(datetime('now')),': ', 'Manufacturer: ', Plan.Manufacturer]
%                             ['---------------------','--------------------','-----------------']}
% 
%                 app.Status_2.Value = [current_message2; new_message2]
                %keep the window focused on the latest iteration
                scroll(app.Status,'bottom')
                disp(fileNameCSV)
                %call the function in parallel
                %fcn_matcher = parfeval(app.p,@MatcherLogToPlan,0,fileNameCSV,OutFolder,3,3,10)
                %MatcherLogToPlan(app,fileNameCSV,OutFolder,1,4,10)
                
                %MatcherLogToPlan(app,app.LogID,app.LogRx,app.LogBeam,app.LogMahine,fileNameCSV,OutFolder)
                 [folder, fname] = fileparts(fileNameCSV);
                 fname2 = regexprep(fname,'[^ a-z-A-Z0-9]','');
                 %RxMatches = regexprep(RxMatches,'[^ \,\-\.a-z-A-Z0-9]','');
                cmap = parula;
                cmap(1,:) = [0.13,0.19,0.24];


                if contains(fileNameCSV,'_E.csv')
                    
                    contourf(app.ExpectedAxes,readmatrix(fileNameCSV,"Delimiter",','))
                    text(app.ExpectedAxes,15,380,'Expected Fluence','Color','#00897b','FontSize',20,'FontWeight','bold')
                    text(app.ExpectedAxes,10,15,fname2,'Color','White','FontSize',10,'FontWeight','bold')
                    colormap(app.ExpectedAxes,cmap)
                    axis(app.ExpectedAxes,'tight')  
                    cb = colorbar(app.ExpectedAxes,'Location','east');
                    cb.Color = 'white'; 
                    
                    %box(app.ExpectedAxes,"on")
                   
                    drawnow

                    line(app.ExpectedAxes,[200 200],[40 360],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    line(app.ExpectedAxes,[40 360],[200 200],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    drawnow
                    
                    zip(strcat(OutFolder,fname),fileNameCSV,OutFolder)
                    delete(fileNameCSV)
                   

                elseif contains(fileNameCSV,'_G.csv')
            
                    gamma = flipud(readmatrix(fileNameCSV,"Delimiter",','));
                    
                    imagesc(app.GammaAxes,gamma(:,:));
                    map = jet(256);
                    map(1,:) = [0.13,0.19,0.24];
                    map(128:end,1) = 0.8392;
                    map(128:end,2) = 0;
                    map(128:end,3) = 0.2157;
                    
                    clim(app.GammaAxes,[0 2])
                    app.GammaAxes.YDir = 'reverse';
                    colormap(app.GammaAxes,map);
                    axis(app.GammaAxes,'tight')  
                    text(app.GammaAxes,15,15,'Gamma Map','Color','#00897b','FontSize',20,'FontWeight','bold')
                    text(app.GammaAxes,10,385,fname2,'Color','White','FontSize',10,'FontWeight','bold')
                    cb = colorbar(app.GammaAxes,'Location','east');
                    cb.Color = 'white';
                    %colormap(app.GammaAxes,cmap)
                    drawnow
                    line(app.GammaAxes,[200 200],[40 360],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    line(app.GammaAxes,[40 360],[200 200],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    drawnow
                    %zip up the data and delete the old
                    zip(strcat(OutFolder,fname),fileNameCSV,OutFolder)
                    delete(fileNameCSV)

                elseif contains(fileNameCSV,'_A.csv')
                    
                    contourf(app.ActualAxes,readmatrix(fileNameCSV,"Delimiter",','))
                    text(app.ActualAxes,15,380,'Actual Fluence','Color','#00897b','FontSize',20,'FontWeight','bold')
                    text(app.ActualAxes,10,15,fname2,'Color','White','FontSize',10,'FontWeight','bold')
                    colormap(app.ActualAxes,cmap)
                    axis(app.ActualAxes,'tight')  
                    cb = colorbar(app.ActualAxes,'Location','east');
                    cb.Color = 'white';     
                  
                    drawnow
                    line(app.ActualAxes,[200 200],[40 360],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    line(app.ActualAxes,[40 360],[200 200],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    drawnow

                    zip(strcat(OutFolder,fname),fileNameCSV,OutFolder)
                    delete(fileNameCSV)
                elseif contains(fileNameCSV,'_P.csv')
                    
                    contourf(app.ExpectedAxes,readmatrix(fileNameCSV,"Delimiter",','))
                    text(app.ExpectedAxes,15,380,'Tx Plan Fluence','Color','#00897b','FontSize',20,'FontWeight','bold')
                    text(app.ExpectedAxes,10,15,fname2,'Color','White','FontSize',10,'FontWeight','bold')
                    colormap(app.ExpectedAxes,cmap)
                    axis(app.ExpectedAxes,'tight')  
                    cb = colorbar(app.ExpectedAxes,'Location','east');
                    cb.Color = 'white';     
                  
                    drawnow
                    line(app.ExpectedAxes,[200 200],[40 360],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    line(app.ExpectedAxes,[40 360],[200 200],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    drawnow

                    zip(strcat(OutFolder,fname),fileNameCSV,OutFolder)
                    delete(fileNameCSV)
                elseif contains(fileNameCSV,'_TOTAL_P.csv')

                    contourf(app.ExpectedAxes,readmatrix(fileNameCSV,"Delimiter",','))
                    text(app.ExpectedAxes,15,380,'Tx Plan Fluence','Color','#00897b','FontSize',20,'FontWeight','bold')
                    text(app.ExpectedAxes,10,15,fname2,'Color','White','FontSize',10,'FontWeight','bold')
                    colormap(app.ExpectedAxes,cmap)
                    axis(app.ExpectedAxes,'tight')  
                    cb = colorbar(app.ExpectedAxes,'Location','east');
                    cb.Color = 'white';     
                  
                    drawnow
                    line(app.ExpectedAxes,[200 200],[40 360],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    line(app.ExpectedAxes,[40 360],[200 200],'LineWidth',0.5,'Color','w','LineStyle','-.')
                    drawnow

                    zip(strcat(OutFolder,fname),fileNameCSV,OutFolder)
                    delete(fileNameCSV)

                else
                    msgbox('File Not Recognised!')
                    return
                    
                end

                        
         end

        function CALC_GAMMA(app, filePathEval, filePathRef) 
            
            %GammaEval(app, app.LogID, app.LogRx, app.LogBeam, app.LogMachine, app.filePathEval, app.filePathRef,3,3,10)
            

        end

        function TLOG_CHECK(app, fileNameTDS)
            p = app.p;
            

            OutFolder = app.OutputFolder.Value;
            GammaDD = app.GammaDDEditField.Value;
            GammaDD = GammaDD/100;
            GammaDTA = app.GammadtammEditField.Value;
            GammaThresh = app.GammathreshEditField.Value;
            GammaThresh = GammaThresh/100;
            PassingCritera = app.PassingCritEditField.Value;
            PassingCritera = PassingCritera/100;


            fcn_tlog = parfeval(p, @eFluenceQA_Tlog, 22, app, fileNameTDS, OutFolder, GammaDD, GammaDTA, GammaThresh,  PassingCritera);
                        %[pp_init, Result, fname_out] = fetchOutputs(fcn_tlog,UniformOutput=false);
            
            %disp(strcat('Running TrajLog Fluence Generator on:',fileNameTDS))
            axis(app.UIAxes,'tight')
            hold(app.UIAxes,'on'); 
            
            while (~isempty(p))
                
                [idx,LogDate, ID, Plan, Beam, VarianSerial, pp_init, Result, RMS_MLC, MAX_X1, MAX_X2, MAX_Y1, MAX_Y2, MAX_LAT, MAX_LONG, MAX_VERT, MAX_COLL, MAX_GANTRY, fname_A, fname_E, fname_G, fname_out, BEAM_ON] = fetchNext(fcn_tlog)
                 
                GammaFilename{idx,1} = fname_out;
                LogDateT{idx,1} = cellfun(@datetime,LogDate,'UniformOutput',false);
                %LogDateTime{idx,1} = datetime(LogDateT{idx,1})
                IDT{idx,1} = ID;
                PlanT{idx,1} = Plan;
                BeamT{idx,1} = squeeze(Beam);
                VarianMachineSerial{idx,1} = VarianSerial;
                GammaPR{idx,1} = pp_init;
                GammaResult{idx,1} = Result;
                RMS_MLCT{idx,1} = RMS_MLC;
                MAX_X1T{idx,1} = MAX_X1;
                MAX_X2T{idx,1} = MAX_X2;
                MAX_Y1T{idx,1} = MAX_Y1;
                MAX_Y2T{idx,1} = MAX_Y2;
                MAX_LATT{idx,1} = MAX_LAT;
                MAX_LONGT{idx,1} = MAX_LONG;
                MAX_VERTT{idx,1} = MAX_VERT;
                MAX_COLLT{idx,1} = MAX_COLL;
                MAX_GANTRYT{idx,1} = MAX_GANTRY;
                BEAM_ON_DURATION{idx,1} = BEAM_ON;

                fname_AT{idx,1} = fname_A;
                fname_ET{idx,1} = fname_E;
                fname_GT{idx,1} = fname_G;
                
                tDataCell = [LogDateT{idx,1},IDT{idx,1},PlanT{idx,1},BeamT{idx,1},VarianMachineSerial{idx,1},GammaPR{idx,1},GammaResult{idx,1},RMS_MLCT{idx,1},MAX_X1T{idx,1},MAX_X2T{idx,1},MAX_Y1T{idx,1},MAX_Y2T{idx,1},MAX_LATT{idx,1},MAX_LONGT{idx,1},MAX_VERTT{idx,1},MAX_COLLT{idx,1},MAX_GANTRYT{idx,1},BEAM_ON_DURATION{idx,1},fname_AT{idx,1},fname_ET{idx,1},fname_GT{idx,1}];
                %cellfun(@char,tDataCell,'UniformOutput',false)
                tData = cell2table(tDataCell);
                tData.Properties.VariableNames = {'DATE','ID','PLAN','BEAM','MACHINE','GPR [%]','RESULT','MLC RMSE [cm]','MAX X1 ERR. [cm]','MAX X2 ERR. [cm]','MAX Y1 ERR. [cm]','MAX Y2 ERR. [cm]','MAX CCH LAT ERR [cm]','MAX CCH LONG ERR. [cm]','MAX CCH VERT ERR. [cm]','MAX COLL ERR. [deg]','MAX GANTRY ERR. [deg]','BEAM ON TIME [secs]','fname_A','fname_E','fname_G'};
                app.tData = tData;

                TableData = app.UITable.Data; %current data
                %update the table data
                app.UITable.Data = [TableData; tData];
                MeanData = table2cell(app.UITable.Data(:,6));
                MeanData = cell2mat(MeanData);
                MeanGammaPR = mean(MeanData)
% %                 find gamma values < passing criteria
% %                 PassFail = app.PassingCritEditField.Value;
% %                 gData = table2cell(app.UITable.Data)
% %                 gData = cell2mat(gData)
% %                 styleIndices = find(gData < PassFail)
% %                 [rowt,colt] = find(styleIndices);
%              
%                 s = uistyle('BackgroundColor','red');
%                 addStyle(app.UITable,s,'cell',[rowt,6]);


                for i = 1:size(GammaResult{idx},1)    
                    if  contains(GammaResult{idx}(i),'FAIL')
                        s = plot(app.UIAxes,datetime('now'),cell2mat(GammaPR{idx,1}(i)),'ro', 'MarkerFaceColor','red','MarkerSize', 6);
                        axis(app.UIAxes, 'auto x')
                        ylim(app.UIAxes,[0 105])

                        dt = datatip(s,datetime('now'),cell2mat(GammaPR{idx,1}(i)));
                        s.DataTipTemplate.Interpreter = 'none';
                        s.DataTipTemplate.DataTipRows(1).Label = 'Date: ';
                        s.DataTipTemplate.DataTipRows(2).Label = 'Gamma Pass Rate[%]: ';
                        s.DataTipTemplate.DataTipRows(3).Label = ['Traj. Log: ', upper(GammaFilename{idx}(1,1:end-15))];
                        s.DataTipTemplate.DataTipRows(4).Label = ['Machine: ', upper(cell2mat(VarianMachineSerial{idx,1}(i)))];
                        s.DataTipTemplate.DataTipRows(5).Label = ['!GAMMA FAILED! ', char(hex2dec('2716'))];
                        beep();
                        
                        
                        drawnow  
                    else
                        
                        s = plot(app.UIAxes,datetime('now'),cell2mat(GammaPR{idx,1}(i)),'bo', 'MarkerFaceColor','green','MarkerSize', 6);
                        axis(app.UIAxes, 'auto x')
                        ylim(app.UIAxes,[0 105])

                        s.DataTipTemplate.Interpreter = 'none';
                        s.DataTipTemplate.DataTipRows(1).Label = 'Date: ';
                        s.DataTipTemplate.DataTipRows(2).Label = 'Gamma Pass Rate[%]: ';
                        s.DataTipTemplate.DataTipRows(3).Label = ['Traj. Log: ', upper(GammaFilename{idx}(1,1:end-15))];
                        s.DataTipTemplate.DataTipRows(4).Label = ['Machine: ', upper(cell2mat(VarianMachineSerial{idx,1}(i)))];
                        s.DataTipTemplate.DataTipRows(5).Label = ['GAMMA PASSED: ', char(hex2dec('2713'))];
                        drawnow  
                    end
                end


                l = legend(app.UIAxes,strcat('Mean:',{' '}, num2str(MeanGammaPR)));
                l.Color = [1 1 1];
                l.FontSize = 12;
                l.Location = 'southeast';
                app.AverageGammaPassRateGauge.Value = MeanGammaPR;

                if MeanGammaPR > PassingCritera
                    app.AverageGammaPassRateLabel.FontColor = 'green';
                else
                    app.AverageGammaPassRateLabel.FontColor = 'red';
                end
                h = findall(0,'type','figure','name','LiveStream');
                if isempty(h)
                   disp('no figure founc')
                else
                   PlotTableButtonPushed(app)
                end
                

                %%disp(strcat(idx,'File: ',GammaFilename{idx},'Passrate: ',GammaResult{idx},'%',' Result: ',GammaResult{idx}))
                               
            end
            
           
            %{[datestr(datetime('now')),': ',fname_out, 'Gamma: ', num2str(app.pp_init), ' Result:', app.Result]}
            %current_message2 = app.Status_2.Value;
            %new_message2 = {[datestr(datetime('now')),': ',fname_out, 'Gamma: ', cellstr(pp_init), ' Result:', Result]};
            %app.Status_2.Value = [current_message2; new_message2];
            scroll(app.Status_2,'bottom')

%             if app.FigureUp == 1
%                 refreshdata
%             end


        end

        
        function MLC_CHECK(app, fileName)
            
            %this function operates if there is an MLC detected
            OutFolder = app.OutputFolder.Value;
            fInfo = dicominfo(fileName);
            Modality = fInfo.Modality; %'RTPLAN'
            if strcmp(Modality,'RTPLAN') == 0
                %app.Status.Value = strcat(datestr(datetime('now')),': ', 'Not an RTPLAN... returning to monitoring');
                return
            else
                %pp.Status.Value = strcat(datestr(datetime('now')),': ', 'It is an RTPLAN proceeding to process...');
                %get all the metadata
                try
                    Plan.Filename = fInfo.Filename;
                    %Plan.FileModDate = fInfo.FileModDate;
                    %Plan.InstanceCreationDate = fInfo.InstanceCreationDate; 
                    %Plan.InstanceCreationTime = fInfo.InstanceCreationTime; 
                    %Plan.SOPClassUID = fInfo.SOPClassUID;
                    %Plan.SOPInstanceUID = fInfo.SOPInstanceUID;
                    %Plan.StudyDate = fInfo.StudyDate;
                    %Plan.StudyTime = fInfo.StudyTime;
                    %Plan.SeriesTime = fInfo.SeriesTime;
                    Plan.Manufacturer = fInfo.Manufacturer; %Varian or Elekta
                    %Plan.InstitutionName = fInfo.InstitutionName; %Facility GC
                    %Plan.PhysiciansOfRecord = fInfo.PhysiciansOfRecord.FamilyName; %RO
                    %Plan.OperatorsName = fInfo.OperatorsName; %planner
                    %Plan.SeriesDescription = fInfo.SeriesDescription;
                    %Plan.ManufacturerModelName = fInfo.ManufacturerModelName;
                    %Plan.PatientFirstName = fInfo.PatientName.GivenName;
                    %Plan.PatientLastName = fInfo.PatientName.FamilyName;
                    Plan.PatientID = fInfo.PatientID;
                    %Plan.PatientDOB = fInfo.PatientBirthDate;
                    %Plan.PatietnSex = fInfo.PatientSex;
                    Plan.RTPlanLabel = fInfo.RTPlanLabel;
                    Plan.RTPlanDate = fInfo.RTPlanDate;
                    Plan.ApprovalStatus = fInfo.ApprovalStatus;
                 catch
                    Plan.Filename = 'anon';
                    %Plan.FileModDate = 'anon';
                    %Plan.InstanceCreationDate = 'anon';
                    %Plan.InstanceCreationTime = 'anon';
                    %Plan.SOPClassUID = 'anon';
                    %Plan.SOPInstanceUID = 'anon';
                    %Plan.StudyDate = 'anon';
                    %Plan.StudyTime = 'anon';
                    %Plan.SeriesTime = 'anon';
                    Plan.Manufacturer = fInfo.Manufacturer; %Varian or Elekta
                    %Plan.InstitutionName = 'anon';%Facility GC
                    %Plan.PhysiciansOfRecord = 'anon'; %RO
                    %Plan.OperatorsName = 'anon'; %planner
                    %Plan.SeriesDescription ='anon';
                    %Plan.ManufacturerModelName = 'anon';
                    %Plan.PatientFirstName = 'anon';
                    %Plan.PatientLastName = 'anon';
                    Plan.PatientID = 'anon';
                    %Plan.PatientDOB = 'anon';
                    %Plan.PatietnSex = 'anon';
                    Plan.RTPlanLabel = 'anon';
                    Plan.RTPlanDate = 'anon';
                    Plan.ApprovalStatus = 'anon';
                end
                %app.Status.Value = strcat(datestr(datetime('now')),': ', 'New File Detected:',fileName);
                %Plan.ReviewDate = fInfo.ReviewDate;
                current_message = app.Status.Value;

                new_message = {[datestr(datetime('now')),': ', 'Filename: ',Plan.Filename];
                            [datestr(datetime('now')),': ', 'Patient ID: ',Plan.PatientID];
                            [datestr(datetime('now')),': ', 'Plan Name: ',Plan.RTPlanLabel];
                            [datestr(datetime('now')),': ', 'Plan Date: ',Plan.RTPlanDate];
                            [datestr(datetime('now')),': ', 'Approval Status: ',Plan.ApprovalStatus];
                            [datestr(datetime('now')),': ', 'Manufacturer: ', Plan.Manufacturer]
                            ['---------------------','--------------------','-----------------']};

                app.Status.Value = [current_message; new_message];
                scroll(app.Status,'bottom')
                %app.Status.Value = new_message;
                %notify the messenger
                
               
                if contains(Plan.Manufacturer,'VARIAN','IgnoreCase',true)
                    %disp('Varian')
                    p = app.p;
                    fcn_mmlc = parfeval(p,@eFluenceQA_MMLC,5,fileName,OutFolder)
                    %[PlanName, PlanID, PlanField, PlanMachine, PlanRx] = fetchOutputs(fcn_agl)
                elseif contains(Plan.Manufacturer,'Elekta','IgnoreCase',true) || contains(Plan.Manufacturer,'CMS','IgnoreCase',true) || contains(Plan.Manufacturer,'Monaco','IgnoreCase',true)
                    %disp('Elekta')
                    p = app.p;
                    fcn_agl = parfeval(p,@eFluenceQA_Agility,5,fileName,OutFolder)
                    %[PlanName, PlanID, PlanField, PlanMachine, PlanRx] = fetchOutputs(fcn_mmlc)
                end
            end
        end 
    end
            
    
    methods (Access = private)
        
        function updateTable(app)

            %disp(app.pp_init)
            %disp(app.Result)
            %disp(app.fname)

            
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartMonitoringSwitch
        function StartMonitoringSwitchValueChanged(app, event)
            value = app.StartMonitoringSwitch.Value;
            

                if strcmp(value,'On')
%                     javaaddpath('tablefilter-swing-4.1.4.jar');
%                     jscrollpane = findjobj(app.UITable);
%                     jtable = jscrollpane.getViewport.getView;
%                     jtable.GetModel();
%                     filter = net.coderazzi.filters.gui.TableFilterHeader(jtable);
                    
                    app.StartMonitoringSwitch.Enable = 'off';
                    pathToWatch = app.dcmFolder.Value;  %get the plan path
                    %disp(pathToWatch)
                    pathToWatch2 = app.TDSFolder.Value; %get the TDS path
                    pathToWatch3 = app.OutputFolder.Value;
                    app.Status.Value = strcat(datestr(datetime('now')),': ', ' Spooling parallel engine, please wait...');
                    app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Spooling parallel engine, please wait...');
                    pause(1) %give the software a chance to update
                    p = parpool(2); 
                    
                    app.p = p; %update the app properties
                    
                    app.Lamp.Color = 'green';
                    app.Lamp_2.Color = 'green';
                    app.StartMonitoringSwitchLabel.Text = 'Monitoring...';
    
                    app.Status.Value = strcat(datestr(datetime('now')),': ', ' Checking for new plan files...');
                    app.Status.Value = strcat(datestr(datetime('now')),': ', ' Looking in folder:', pathToWatch);
                    app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Checking for new trajectory / iCOM files...');
                    app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Looking in folder:', pathToWatch2);
                    FileWatch(app,pathToWatch); %PLANS
                    FileWatch2(app,pathToWatch2); %TLOGS
                    FileWatch3(app,pathToWatch2); %iCOMs
                    FileWatch4(app,pathToWatch3); %new outputs
                    app.StartMonitoringSwitch.Enable = 'on';
                else
                    app.Lamp.Color = 'red';
                    app.Lamp_2.Color = 'red';
                    app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring Stopped.');
                    app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Monitoring Stopped.');
                    app.StartMonitoringSwitchLabel.Text = 'Start Monitoring';
                    delete fileObj1
                    delete fileObj2
                    delete fileObj3
                    delete fileObj4
                    delete(gcp('nocreate'))
                end
%             catch
%                 delete(gcp('nocreate'))
%                 app.StartMonitoringSwitch.Enable = 'on';
%                 errordlg('Could not start monitoring - contact support','Error!','modal')
%             end


        end

        % Close request function: Sentinel
        function ShpeherdScanCloseRequest(app, event)
            delete fileObj1
            delete fileObj2
            delete(gcp('nocreate'))
            delete(app)
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)


        end

        % Button pushed function: PlotTableButton
        function PlotTableButtonPushed(app, event)
            %app.PlotTableButton.Enable =  "off";
            set(app.Sentinel,'Pointer','watch');
            drawnow;
            TablePlotFigure = figure(1);
            TablePlotFigure.Name = 'LiveStream'
            TablePlotFigure.Units = 'normalized';
            TablePlotFigure.Color = [0,0,0];
            TablePlotFigure.Position = [0 0 0.5 1];
            tDataSorted = sortrows(app.UITable.Data);
            %tDataSorted = app.UITable.Data;   
            sp = stackedplot(tDataSorted,'XVariable',"DATE");
            ax = findobj(sp.NodeChildren, 'Type','Axes');
            set(ax,'XColor','#0FC27B')
            set(ax,'YColor','#0FC27B')
            set(ax,'XGrid','on')
            set(ax,'YGrid','on')
            set(ax,'GridColor',[1 1 1])
            set(ax,'color',[0.13,0.19,0.24]);
            set(ax,'MinorGridColor',[1 1 1])
            %set(sp, 'Color','w', 'XColor','w', 'YColor','w')
            %sp.Color = [1 1 1]
            sp.LineWidth = 2;
            sp.FontSize = 11;
            sp.LineProperties(1).Color = '#BB86FC';
            sp.LineProperties(2).Color = '#6200EE';
            sp.LineProperties(3).Color = '#03DAC5';
            sp.LineProperties(4).Color = '#FF7597';
            sp.LineProperties(5).Color = '#3700B3';
            sp.LineProperties(6).Color = '#60C689';
            sp.LineProperties(7).Color = '#57ACDC';
            sp.LineProperties(8).Color = '#C2185B';
            sp.LineProperties(9).Color = '#5727B0';
            sp.LineProperties(10).Color = '#57ACDC';
            sp.LineProperties(11).Color = '#FAC319';
            sp.LineProperties(12).PlotType = "stairs";
            sp.Marker = "o";
            grid on
            
            refreshdata
            
            set(app.Sentinel,'Pointer','arrow');
            drawnow;


            %app.PlotTableButton.Enable =  "on";
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Sentinel.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {815, 815};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {157, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Sentinel = uifigure('Visible', 'off');
            app.Sentinel.Name = 'SentinelScan';
            app.Sentinel.Icon = fullfile(pathToMLAPP, 'Logos', 'ICON.png');
            app.Sentinel.CloseRequestFcn = createCallbackFcn(app, @ShpeherdScanCloseRequest, true);
            app.Sentinel.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Sentinel.Tag = 'SentinelScan';
            app.GridLayout = uigridlayout(app.Sentinel);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DCMROOTFolderLabel = uilabel(app.LeftPanel);
            app.DCMROOTFolderLabel.Text = 'DCM ROOT Folder:';
            app.StartMonitoringSwitchLabel = uilabel(app.LeftPanel);
            app.StartMonitoringSwitchLabel.Text = 'Start Monitoring';
            app.StartMonitoringSwitch = uiswitch(app.LeftPanel, 'slider');
            app.StartMonitoringSwitch.ValueChangedFcn = createCallbackFcn(app, @StartMonitoringSwitchValueChanged, true);
            app.LOGROOTFolderLabel = uilabel(app.LeftPanel);
            app.LOGROOTFolderLabel.Text = 'LOG ROOT Folder:';
            app.OutputFolderLabel = uilabel(app.LeftPanel);
            app.OutputFolderLabel.Text = 'Output Folder:';
            app.GammaDDLabel = uilabel(app.LeftPanel);
            app.GammaDDLabel.Text = 'Gamma DD [ %]:';
            app.GammaDDEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GammaDDEditField.Value = 1;
            app.GammadtammLabel = uilabel(app.LeftPanel);
            app.GammadtammLabel.Text = 'Gamma dta [mm]:';
            app.GammadtammEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GammadtammEditField.Value = 0.5;
            app.GammathreshLabel = uilabel(app.LeftPanel);
            app.GammathreshLabel.Text = 'Gamma thresh. [%]:';
            app.GammathreshEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GammathreshEditField.Value = 10;
            app.PassingCriteriaLabel = uilabel(app.LeftPanel);
            app.PassingCriteriaLabel.Text = 'Passing Crit. [%]:';
            app.PassingCritEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PassingCritEditField.Value = 95;
            app.AverageGammaPassRateLabel = uilabel(app.LeftPanel);
            app.AverageGammaPassRateLabel.Tag = 'GaugeLabel';
            app.AverageGammaPassRateLabel.Text = {'Average Gamma '; 'Pass Rate [%]'};
            app.AverageGammaPassRateGauge = uigauge(app.LeftPanel, 'circular');
            app.PlotTableButton = uibutton(app.LeftPanel, 'push');
            app.PlotTableButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTableButtonPushed, true);
            app.PlotTableButton.Text = 'Plot Table';
            app.PrintReportButton = uibutton(app.LeftPanel, 'push');
            app.PrintReportButton.Text = 'Print Report';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'GPR [%] ')
            zlabel(app.UIAxes, 'Z')
            app.ExpectedAxes = uiaxes(app.RightPanel);
            app.ExpectedAxes.XTick = [];
            app.ExpectedAxes.XTickLabel = {};
            app.ExpectedAxes.YAxisLocation = 'origin';
            app.ExpectedAxes.YTick = [];
            app.ExpectedAxes.YTickLabel = {};
            app.ExpectedAxes.ZTick = [];
            app.ExpectedAxes.ZTickLabel = '';
            app.ActualAxes = uiaxes(app.RightPanel);
            app.ActualAxes.XTick = [];
            app.ActualAxes.XTickLabel = '';
            app.ActualAxes.YAxisLocation = 'origin';
            app.ActualAxes.YTick = [];
            app.ActualAxes.YTickLabel = '';
            app.ActualAxes.ZTick = [];
            app.ActualAxes.ZTickLabel = '';
            app.GammaAxes = uiaxes(app.RightPanel);
            app.GammaAxes.XTick = [];
            app.GammaAxes.XTickLabel = '';
            app.GammaAxes.YAxisLocation = 'origin';
            app.GammaAxes.YTick = [];
            app.GammaAxes.YTickLabel = '';
            app.GammaAxes.ZTick = [];
            app.GammaAxes.ZTickLabel = '';
            app.Status = uitextarea(app.RightPanel);
            app.Status.Editable = 'off';
            app.Status.Value = {'Idle'};
            app.dcmFolder = uieditfield(app.RightPanel, 'text');
            app.dcmFolder.Value = 'C:\Temp\Plans\';
            app.TDSFolder = uieditfield(app.RightPanel, 'text');
            app.TDSFolder.Value = 'C:\Temp\Logs\';
            app.Status_2 = uitextarea(app.RightPanel);
            app.Status_2.Editable = 'off';
            app.Status_2.Value = {'Idle'};
            app.PLANFILESFOUNDLabel = uilabel(app.RightPanel);
            app.PLANFILESFOUNDLabel.Text = 'PLAN FILES FOUND';
            app.TRAJECTORYFILESFOUNDLabel = uilabel(app.RightPanel);
            app.TRAJECTORYFILESFOUNDLabel.Text = 'TRAJECTORY FILES FOUND';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'DATE'; 'ID'; 'PLAN'; 'BEAM'; 'MACHINE'; 'GPR [%]'; 'RESULT'; 'MLC RMSE [cm]'; 'MAX X1 ERR. [cm]'; 'MAX Y2 ERR. [cm]'; 'MAX Y1 ERR. [cm]'; 'MAX Y2 ERR. [cm]'; 'MAX CCH LAT ERR [cm]'; 'MAX CCH LONG ERR. [cm]'; 'MAX CCH VERT ERR. [cm]'; 'MAX COLL ERR. [deg]'; 'MAX GANTRY ERR. [deg]'; 'BEAM ON TIME [s]'; 'fname_A'; 'fname_E'; 'fname_G'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true false true true true true true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RESULTSTABLELabel = uilabel(app.RightPanel);
            app.RESULTSTABLELabel.Text = 'RESULTS TABLE';
            app.Lamp = uilamp(app.RightPanel);
            app.Lamp_2 = uilamp(app.RightPanel);
            app.OutputFolder = uieditfield(app.RightPanel, 'text');
            app.OutputFolder.Value = 'C:\Temp\Output\';
            % Show the figure after all components are created
            app.Sentinel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SentinelScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sentinel)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sentinel)
        end
    end
end